classdef BilibiliMKV_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Hyperlink            matlab.ui.control.Hyperlink
        PathmkvtoolnixLabel  matlab.ui.control.Label
        Progress             matlab.ui.control.EditField
        Label_3              matlab.ui.control.Label
        BMKVByRanZhuSoutheastUniversityLabel  matlab.ui.control.Label
        Button               matlab.ui.control.StateButton
        Button_3             matlab.ui.control.Button
        Button_2             matlab.ui.control.Button
        EditField_2          matlab.ui.control.EditField
        Label_2              matlab.ui.control.Label
        EditField            matlab.ui.control.EditField
        Label                matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            input_path=app.EditField.Value;
            output_path=app.EditField_2.Value;
            app.Progress.Value='程序已进行：0%';  %赋初值
            sum=0;  %赋初值
            temp=0;  %赋初值
            if ~exist(output_path, 'dir')
            mkdir(output_path);
            end
            % 获取文件夹中所有子文件夹
            subfolders = dir(input_path);
            subfolders = subfolders([subfolders(:).isdir]); % 只保留子文件夹
            subfolders = subfolders(~ismember({subfolders(:).name},{'.','..'})); % 去掉'.'和'..'
            % 遍历每个子文件夹
            for i = 1:length(subfolders)
                sum=sum+i;
                temp=temp+1;
                app.Progress.Value=['程序已进行：',num2str(round(100*temp/length(subfolders))),'%'];  %输出文字百分比；round函数四舍五入取整
                drawnow  %实时更新图窗
                % 获取子文件夹路径
                subfolder_path = fullfile(input_path, subfolders(i).name);
                
                % 获取子文件夹中所有的子文件夹
                subsubfolders = dir(subfolder_path);
                subsubfolders = subsubfolders([subsubfolders(:).isdir]); % 只保留子文件夹
                subsubfolders = subsubfolders(~ismember({subsubfolders(:).name},{'.','..'})); % 去掉'.'和'..'
                
                % 遍历每个子文件夹中的子文件夹
                for j = 1:length(subsubfolders)
                    % 获取子文件夹中的子文件夹路径
                    subsubfolder_path = fullfile(subfolder_path, subsubfolders(j).name);
                    subsubsubfolders = dir(subsubfolder_path);
                    subsubsubfolders = subsubsubfolders([subsubsubfolders(:).isdir]); % 只保留子文件夹
                    subsubsubfolders = subsubsubfolders(~ismember({subsubsubfolders(:).name},{'.','..'})); % 去掉'.'和'..'
                    subsubsubfolder_path = fullfile(subsubfolder_path, subsubsubfolders(1).name);
                    % 获取文件夹中的entry.json中的内容
                    entry_file_path = fullfile(subsubfolder_path, 'entry.json');
                    entry_file = jsondecode(fileread(entry_file_path));
                    title = regexprep(entry_file.title, '[^\w]+', ''); % 需要加入正则表达式去掉标点 否则奇怪的文件名会出错
                    % 可能存在分P
                     if isfield(entry_file, 'page_data')
                        part = num2str(entry_file.page_data.page);
                    else
                        part = '无分P';
                     end
                    % 可能有分辨率不存在的问题
                    if isfield(entry_file, 'quality_pithy_description')
                        quality_pithy_description = entry_file.quality_pithy_description;
                    else
                        quality_pithy_description = '未知分辨率';
                    end
                    % 可能有UP主信息丢失问题
                    if isfield(entry_file, 'owner_name')
                        owner_name =  regexprep(entry_file.owner_name, '[^\w]+', '');% 需要加入正则表达式去掉标点 否则奇怪的UP主的名字会出错
                    else
                        owner_name = '未知UP主';
                    end
                    
                    entry_content = ['[分P=' part ']_' title '_' quality_pithy_description '_UP主=' owner_name '_BVid=' num2str(entry_file.bvid)];
                    
                    % 将当前子文件夹中子文件夹中的audio.m4s和video.m4s重命名为{视频标题+UP主姓名+分辨率+AV号.mp3和对应的*.mp4
                    audio_file_path = fullfile(subsubsubfolder_path, 'audio.m4s');
                    video_file_path = fullfile(subsubsubfolder_path, 'video.m4s');
                    new_audio_file_path = fullfile(subsubsubfolder_path, [entry_content, '.mp3']);
                    new_video_file_path = fullfile(subsubsubfolder_path, [entry_content, '.mp4']);
                    movefile(audio_file_path, new_audio_file_path);
                    movefile(video_file_path, new_video_file_path);
                    % 调用mkvmerge将MP3和MP4合并为MKV文件
                    command_mkvmerge = sprintf('mkvmerge -o %s "%s" "%s"', fullfile(output_path, [entry_content, '.mkv']), new_audio_file_path, new_video_file_path); 
                    system(command_mkvmerge);
                end
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            filepath= uigetdir('*.*', '请选择文件夹');
            app.EditField.Value = filepath;
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
              filepath= uigetdir('*.*', '请选择文件夹');
            app.EditField_2.Value = filepath;
        end

        % Callback function
        function UITableDisplayDataChanged(app, event)
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '缓存目录';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '目标输出目录';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '选择缓存';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '选择输出';
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = '开始';
            app.BMKVByRanZhuSoutheastUniversityLabel = uilabel(app.UIFigure);
            app.BMKVByRanZhuSoutheastUniversityLabel.Text = '安卓B站客户端缓存->MKV    By Ran Zhu @Southeast University';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '处理进度';
            app.Progress = uieditfield(app.UIFigure, 'text');
            app.PathmkvtoolnixLabel = uilabel(app.UIFigure);
            app.PathmkvtoolnixLabel.Text = '下载后请在系统环境变量Path中添加mkvtoolnix目录';
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.URL = 'https://mkvtoolnix.download/downloads.html';
            app.Hyperlink.Text = '需要mkvtoolnix才能运行，点我下载';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BilibiliMKV_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end